classdef PoissonBlending < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CreateMaskButton             matlab.ui.control.Button
        SelectTargetButton           matlab.ui.control.Button
        NoSourceFileSelectedLabel_3  matlab.ui.control.Label
        SelectMaskButton             matlab.ui.control.Button
        NoMaskFileSelectedLabel      matlab.ui.control.Label
        NoSourceFileSelectedLabel    matlab.ui.control.Label
        SelectSourceButton           matlab.ui.control.Button
        FILESLabel                   matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ExportButton                 matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        OffsetLabel                  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        sourceFilePath
        maskFilePath
        targetFilePath
        createdMask
        outputImage
    end
    
    methods (Access = private)
        
        function updateButtonStates(app)
            if ~isempty(app.sourceFilePath)
                app.CreateMaskButton.Enable = 'on';
            else
                app.CreateMaskButton.Enable = 'off';
            end
        
            if ~isempty(app.sourceFilePath) && ~isempty(app.targetFilePath) && (~isempty(app.maskFilePath) || ~isempty(app.createdMask))
                app.RUNButton.Enable = 'on';
            else
                app.RUNButton.Enable = 'off';
            end
        
            if ~isempty(app.outputImage)
                app.ExportButton.Enable = 'on';
            else
                app.ExportButton.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectSourceButton
        function SelectSourceButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tiff', 'Image Files (*.jpg, *.png, *.bmp, *.tiff)'});
            if isequal(file, 0)
                app.NoSourceFileSelectedLabel.Text = 'No Source File Selected';
            else
                app.sourceFilePath = fullfile(path, file);
                app.NoSourceFileSelectedLabel.Text = file;
            end
            updateButtonStates(app);
        end

        % Button pushed function: SelectMaskButton
        function SelectMaskButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tiff', 'Image Files (*.jpg, *.png, *.bmp, *.tiff)'});
            if isequal(file, 0)
                app.NoMaskFileSelectedLabel.Text = 'No Mask File Selected';
            else
                app.maskFilePath = fullfile(path, file);
                app.NoMaskFileSelectedLabel.Text = file;
                app.createdMask = [];
            end
            updateButtonStates(app);
        end

        % Button pushed function: CreateMaskButton
        function CreateMaskButtonPushed(app, event)
            if isempty(app.sourceFilePath)
                return;
            end
        
            source = imread(app.sourceFilePath);
            source = im2double(source);
            app.createdMask = getmask(source);
            app.NoMaskFileSelectedLabel.Text = 'Mask Created';
            app.maskFilePath = [];
            updateButtonStates(app);
        end

        % Button pushed function: SelectTargetButton
        function SelectTargetButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tiff', 'Image Files (*.jpg, *.png, *.bmp, *.tiff)'});
            if isequal(file, 0)
                app.NoSourceFileSelectedLabel_3.Text = 'No Target File Selected';
            else
                app.targetFilePath = fullfile(path, file);
                app.NoSourceFileSelectedLabel_3.Text = file;
            end
            updateButtonStates(app);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            if isempty(app.sourceFilePath) || isempty(app.targetFilePath) || (isempty(app.maskFilePath) && isempty(app.createdMask))
                return;
            end
        
            source = imread(app.sourceFilePath);
            target = imread(app.targetFilePath);
            image_offset = [app.XEditField.Value, app.YEditField.Value];
        
            source = im2double(source);
            target = im2double(target);
        
            if ~isempty(app.createdMask)
                mask = app.createdMask;
            else
                mask = imread(app.maskFilePath);
                mask = round(im2double(mask));
            end
        
            [source, mask, target] = fiximages(source, mask, target, image_offset);
            output = imblend(source, mask, target);
            app.outputImage = im2uint8(output);
            app.Image.ImageSource = app.outputImage;
            updateButtonStates(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.outputImage)
                return;
            end
        
            [file, path] = uiputfile('*.jpg', 'Save Image As');
            if isequal(file, 0)
                return;
            else
                imwrite(app.outputImage, fullfile(path, file), 'jpg', 'Quality', 95);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FILESLabel = uilabel(app.LeftPanel);
            app.FILESLabel.Text = 'FILES';
            app.SelectSourceButton = uibutton(app.LeftPanel, 'push');
            app.SelectSourceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSourceButtonPushed, true);
            app.SelectSourceButton.Text = 'Select Source';
            app.NoSourceFileSelectedLabel = uilabel(app.LeftPanel);
            app.NoSourceFileSelectedLabel.Text = 'No Source File Selected';
            app.NoMaskFileSelectedLabel = uilabel(app.LeftPanel);
            app.NoMaskFileSelectedLabel.Text = 'No Mask File Selected';
            app.SelectMaskButton = uibutton(app.LeftPanel, 'push');
            app.SelectMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMaskButtonPushed, true);
            app.SelectMaskButton.Text = 'Select Mask';
            app.NoSourceFileSelectedLabel_3 = uilabel(app.LeftPanel);
            app.NoSourceFileSelectedLabel_3.Text = 'No Target File Selected';
            app.SelectTargetButton = uibutton(app.LeftPanel, 'push');
            app.SelectTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetButtonPushed, true);
            app.SelectTargetButton.Text = 'Select Target';
            app.CreateMaskButton = uibutton(app.LeftPanel, 'push');
            app.CreateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMaskButtonPushed, true);
            app.CreateMaskButton.Enable = 'off';
            app.CreateMaskButton.Text = 'Create Mask';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.OffsetLabel = uilabel(app.RightPanel);
            app.OffsetLabel.Text = 'Offset';
            app.XEditFieldLabel = uilabel(app.RightPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RightPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.RightPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RightPanel, 'numeric');
            app.RUNButton = uibutton(app.RightPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PoissonBlending

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end